classdef visualize_signal < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        UITable                 matlab.ui.control.Table
        time_spinner            matlab.ui.control.Spinner
        TimeLabel               matlab.ui.control.Label
        window_size_spinner     matlab.ui.control.Spinner
        windowsizeSpinnerLabel  matlab.ui.control.Label
        ImportEDFFileButton     matlab.ui.control.Button
        FileNameEditField       matlab.ui.control.EditField
        TimeSlider              matlab.ui.control.Slider
        TimeSliderLabel         matlab.ui.control.Label
        FileNameEditFieldLabel  matlab.ui.control.Label
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        EEG
        info
        start_time
        record_time
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: ImportEDFFileButton
        function ImportEDFFileButtonPushed(app, event)
            [file_name, file_path]=uigetfile('*.edf');
            if isequal(file_name,0)
               disp('User selected Cancel');
            else
                disp(['User selected ', fullfile(file_path,file_name)]);
            
                app.FileNameEditField.Value = fullfile(file_path,file_name);
            
                [data, anns] = edfread(fullfile(file_path,file_name));
                app.EEG = cell2mat(data{:,:})';
                app.info = edfinfo(fullfile(file_path,file_name));
                app.start_time = datetime(app.info.StartDate+"."+app.info.StartTime,"InputFormat","dd.MM.yy.HH.mm.ss");
                                
                t=table;
                t.SingalLabel=app.info.SignalLabels;
                t.Visiable=repelem([false],size(app.info.SignalLabels,1))';
                app.UITable.Data=t;

                fs = app.info.NumSamples(1)/seconds(app.info.DataRecordDuration);
                app.record_time = (0:duration(milliseconds(1e3/fs)):seconds(size(data,1))-duration(milliseconds(1e3/fs)));
                
                plot(app.UIAxes,app.record_time,zeros(size(app.record_time)));
                xlim(app.UIAxes,[seconds(app.TimeSlider.Value) +seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
            
                app.TimeSlider.Limits = [0 size(data,1)];
                app.time_spinner.Limits = [0 size(data,1)];
                
                app.TimeSlider.Enable = 'on';
                app.window_size_spinner.Enable = 'on';
                app.time_spinner.Enable = 'on';
            end
        end

        % Value changed function: window_size_spinner
        function window_size_spinnerValueChanged(app, event)
            value = app.window_size_spinner.Value;
            xlim(app.UIAxes,[seconds(app.TimeSlider.Value) seconds(app.TimeSlider.Value+value)]);
            
        end

        % Value changing function: TimeSlider
        function TimeSliderValueChanging(app, event)
            changingValue = event.Value;
            app.time_spinner.Value = changingValue;
            xlim(app.UIAxes,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            
        end

        % Value changed function: TimeSlider
        function TimeSliderValueChanged(app, event)
            value = app.TimeSlider.Value;
            
        end

        % Value changing function: time_spinner
        function time_spinnerValueChanging(app, event)
            changingValue = event.Value;
            xlim(app.UIAxes,[seconds(changingValue) seconds(changingValue+app.window_size_spinner.Value)]);
            app.TimeSlider.Value = changingValue;
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            cla(app.UIAxes);
            legend_label =[];
            hold(app.UIAxes,"on");
            for i = 1:size(app.UITable.Data,1)
                if app.UITable.Data{i,2}
                    plot(app.UIAxes,app.record_time,app.EEG(i,:));
                    legend_label =[legend_label app.UITable.Data{i,1}];
                end
            end
            for i = 1:size(app.info.Annotations.Onset,1)
                beginning_of_seizure = app.info.Annotations.Onset(i);
                end_of_seizure = app.info.Annotations.Onset(i)+app.info.Annotations.Duration(i);
                xline(app.UIAxes, beginning_of_seizure, 'Color', '#A2142F', 'LineWidth', 2);
                xline(app.UIAxes, end_of_seizure,'Color', '#77AC30', 'LineWidth', 2);
            end
            hold(app.UIAxes,"off");
            xlim(app.UIAxes,[seconds(app.TimeSlider.Value) +seconds(app.TimeSlider.Value+app.window_size_spinner.Value)]);
            disp(legend_label)
            if ~isempty(legend_label)
                legend(app.UIAxes,legend_label);
            else
                legend(app.UIAxes,'off');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'Time')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.FileNameEditFieldLabel = uilabel(app.UIFigure);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.TimeSliderLabel = uilabel(app.UIFigure);
            app.TimeSliderLabel.Text = 'Time';
            app.TimeSlider = uislider(app.UIFigure);
            app.TimeSlider.ValueChangedFcn = createCallbackFcn(app, @TimeSliderValueChanged, true);
            app.TimeSlider.ValueChangingFcn = createCallbackFcn(app, @TimeSliderValueChanging, true);
            app.TimeSlider.Enable = 'off';
            app.FileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileNameEditField.Editable = 'off';
            app.ImportEDFFileButton = uibutton(app.UIFigure, 'push');
            app.ImportEDFFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportEDFFileButtonPushed, true);
            app.ImportEDFFileButton.Text = 'Import EDF File';
            app.windowsizeSpinnerLabel = uilabel(app.UIFigure);
            app.windowsizeSpinnerLabel.Text = 'window size';
            app.window_size_spinner = uispinner(app.UIFigure);
            app.window_size_spinner.Limits = [1 Inf];
            app.window_size_spinner.ValueChangedFcn = createCallbackFcn(app, @window_size_spinnerValueChanged, true);
            app.window_size_spinner.Enable = 'off';
            app.window_size_spinner.Value = 5;
            app.TimeLabel = uilabel(app.UIFigure);
            app.TimeLabel.Text = 'Time';
            app.time_spinner = uispinner(app.UIFigure);
            app.time_spinner.ValueChangingFcn = createCallbackFcn(app, @time_spinnerValueChanging, true);
            app.time_spinner.Limits = [0 Inf];
            app.time_spinner.Enable = 'off';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Singal Label'; 'Enable'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualize_signal

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end